classdef myofibril_profiler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MyofibrilProfilerUIFigure      matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        LoadImageMenu                  matlab.ui.container.Menu
        ExportResultsMenu              matlab.ui.container.Menu
        LoadMeasurementMenu            matlab.ui.container.Menu
        ROIControlsPanel               matlab.ui.container.Panel
        ZLineStainCheckBox             matlab.ui.control.CheckBox
        ROIWidthpxEditField            matlab.ui.control.NumericEditField
        ROIWidthpxEditFieldLabel       matlab.ui.control.Label
        SelectPointsButton             matlab.ui.control.Button
        ProfilerPanel                  matlab.ui.container.Panel
        SummaryTable                   matlab.ui.control.Table
        SarcomereMean                  matlab.ui.control.UIAxes
        Sarcomeres                     matlab.ui.control.UIAxes
        ProfileIntensity               matlab.ui.control.UIAxes
        ProfilerControlsPanel          matlab.ui.container.Panel
        ProminenceEditField            matlab.ui.control.NumericEditField
        ProminenceEditFieldLabel       matlab.ui.control.Label
        CalibrationumpxEditField       matlab.ui.control.NumericEditField
        CalibrationumpxEditFieldLabel  matlab.ui.control.Label
        ChannelSpinner                 matlab.ui.control.Spinner
        ChannelSpinnerLabel            matlab.ui.control.Label
        ImageDisplayPanel              matlab.ui.container.Panel
        ImageAxes                      matlab.ui.control.UIAxes
        DataHandlerPanel               matlab.ui.container.Panel
        ImageFileEditField             matlab.ui.control.EditField
        ImageFileEditFieldLabel        matlab.ui.control.Label
    end


    properties (Access = public)
        myofibril_data = []
        spline_line
        shaded_stats
    end

    methods (Access = public)

        function ExtractProfiles(app)

            im = app.myofibril_data.image;
            xs = app.myofibril_data.profile.xs;
            ys = app.myofibril_data.profile.ys;
            prominence = app.ProminenceEditField.Value;
            calibration = app.CalibrationumpxEditField.Value;
            roi_width = app.ROIWidthpxEditField.Value;

            [prof_x, prof_y, im_profile] = improfile(im, xs, ys);
            
            x_profile = 1:numel(im_profile);


            if roi_width > 1
                 f = waitbar(0,'Please wait...');
                for i = 1 : (numel(prof_x) - 1)
                    ang(i) = atan2((prof_y(i+1) - prof_y(i)), (prof_x(i+1) - prof_x(i)));
                end

                for i = 1 : (numel(prof_x)-1)

                    sx = 1;
                    sy = 1;
                    [H, W] = size(im);
                    phi = ang(i);
                    x0 = prof_x(i);
                    y0 = prof_y(i);

                    T = [sx*cos(phi), -sx*sin(phi), 0
                        sy*sin(phi),  sy*cos(phi), 0
                        (W+1)/2-((sx*x0*cos(phi))+(sy*y0*sin(phi))), (H+1)/2+((sx*x0*sin(phi))-(sy*y0*cos(phi))), 1];

                    tform = affine2d(T);

                    im_rot = imwarp(im, tform, 'OutputView', imref2d([H, W]), 'Interp', 'cubic');

                    cx = W/2;
                    cy = H/2;
                    cy_t = cy+roi_width/2;
                    cy_b = cy-roi_width/2;
                    
                    im_profile_rot(x_profile(i)) = mean(im_rot(cx, (cy - roi_width):(cx - roi_width)));

                    if (mod(i,10) == 1)
                    
                    mes = sprintf('Processing data: %i%% Completed',round(100*i/(numel(x_profile)-1)));
                    waitbar(i/(numel(x_profile)-1),f,mes);
                    
                    end
                    
                end
                delete(f)

                im_profile = im_profile_rot;
                x_profile(end) = [];

            end
            
            [pks_all, locs_all] = findpeaks(-im_profile, ...
            'MinPeakProminence', 0.1*prominence * range(im_profile));

            [pks_z_line, locs_z_line] = findpeaks(-im_profile, ...
            'MinPeakProminence', prominence * range(im_profile));

            [pks_m_line] = pks_all(~ismember(pks_all, pks_z_line));
            [locs_m_line] = locs_all(~ismember(locs_all, locs_z_line));

            pks_m_line(locs_m_line<locs_z_line(1) | locs_m_line>locs_z_line(end)) = [];
            locs_m_line(locs_m_line<locs_z_line(1) | locs_m_line>locs_z_line(end)) = [];

            [pks_p2p, locs_p2p] = findpeaks(im_profile, ...
                'MinPeakProminence', 0.5*prominence * range(im_profile));

            no_of_sarcomeres = numel(locs_z_line) - 1;

            for i = 1 : no_of_sarcomeres
                sarc_len(i) = calibration*(xs(locs_z_line(i+1)) - xs(locs_z_line(i)))

            end

            mean_sarc_len = mean(sarc_len)
            return
            

            x_sarc_profile = linspace(-1, 1, 1000);

            for i = 1 : no_of_sarcomeres

                profile_indices = locs_z_line(i) : locs_z_line(i+1);

                x_ind = profile_indices - locs_m_line(i);
                x_ind = x_ind./max(x_ind)
                
                x_sarc_profile = [];
                x_sarc_profile = linspace(min(x_ind), max(x_ind), 1000)

                x_temp = normalize(profile_indices, 'range');
                y_sarc_profile(i, :) = interp1(x_ind, im_profile(profile_indices), ...
                    x_sarc_profile);

                y_sarc_profile(i,:) = normalize(y_sarc_profile(i,:),'range');

                % [pks_all, locs_all] = findpeaks(-im_profile, ...
                %     'MinPeakProminence', 0.1*prominence * range(im_profile));
                
                % fwhm_ix(i,1) = find(y_sarc_profile(i,:) >= 0.5*y_sarc_profile(i,locs_p2p(p_ix(1))),1,'first');
                % fwhm_ix(i,2) = find(y_sarc_profile(i,:) >= 0.5*y_sarc_profile(i,locs_p2p(p_ix(2))),1,'last');

                % 


            end
            % fwhm_ix
            mean_sarc_profile = mean(y_sarc_profile);

            % fwhm = x_sarc_profile(fwhm_ix(:,2)) - x_sarc_profile(fwhm_ix(:,1))

            hold(app.ProfileIntensity,'on')
            plot(app.ProfileIntensity,x_profile,im_profile,'b-');
            scatter(app.ProfileIntensity,locs_z_line,-pks_z_line,80,'d','MarkerEdgeColor',[0 0 0],...
                                                      'MarkerFaceColor',[0 1 0],...
                                                      'MarkerFaceAlpha',0.5)

            scatter(app.ProfileIntensity,locs_m_line,-pks_m_line,80,'d','MarkerEdgeColor',[0 0 0],...
                                                                    'MarkerFaceColor',[1 0 1],...
                                                                    'MarkerFaceAlpha',0.5)
            scatter(app.ProfileIntensity,locs_p2p,pks_p2p,80,'d','MarkerEdgeColor',[0 0 0],...
                                                                        'MarkerFaceColor',[1 0 0],...
                                                                        'MarkerFaceAlpha',0.5)
            
            
            hold(app.Sarcomeres,'on')
            plot(app.Sarcomeres,x_sarc_profile,y_sarc_profile)
            colororder(app.Sarcomeres,parula(size(y_sarc_profile,1)))
             
            shadedErrorBar2(x_sarc_profile, y_sarc_profile, {@mean, @std},'-k',0,app.SarcomereMean);
            hold(app.SarcomereMean,'on')
            plot(app.SarcomereMean,x_sarc_profile(fwhm_ix(1):fwhm_ix(2)),linspace(mean_sarc_profile(fwhm_ix(1)),...
                mean_sarc_profile(fwhm_ix(2)),numel(fwhm_ix(1):fwhm_ix(2))),':m')
            xlim(app.Sarcomeres,[0 mean_sarc_len])
            xlim(app.SarcomereMean,[0 mean_sarc_len])
            
            mean_sarc_len_leg = sprintf("Mean sarcomere length = %.2f um", mean_sarc_len)
            fwhm_leg = sprintf('FWHM = %.2f um',fwhm);
            legend(app.SarcomereMean,'','','',mean_sarc_len_leg,fwhm_leg)

            app.myofibril_data.profile.intensity = im_profile;
            app.myofibril_data.profile.sarcomere_intensities = y_sarc_profile;
            app.myofibril_data.profile.sarcomere_location = x_sarc_profile;
            app.myofibril_data.profile.mean_sarcomere_profile = mean_sarc_profile;
            app.myofibril_data.profile.std_sarcomere_profile = std(y_sarc_profile);
            app.myofibril_data.profile.sem_sarcomere_profile = std(y_sarc_profile)/sqrt(length(y_sarc_profile));
            app.myofibril_data.profile.sarcomere_lengths = sarc_len;
            app.myofibril_data.profile.fwhm = fwhm;
            app.myofibril_data.calibration = calibration;


        end

        function RefreshDisplay(app)

            ax = {'ProfileIntensity','Sarcomeres','SarcomereMean'};

            for i = 1:numel(ax)
                cla(app.(ax{i}))
            end

            app.SummaryTable.Data = []
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('utilities'))
            movegui(app.MyofibrilProfilerUIFigure,'center')
            colormap(app.ImageAxes, 'gray');
        end

        % Menu selected function: LoadImageMenu
        function LoadImageMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file_string,path_string]=uigetfile2( ...
                {'*.nd2','ND2';'*.png','PNG'}, ...
                'Select Image File');
            if (path_string~=0)
                delete(f)
                app.myofibril_data = [];
                app.myofibril_data.image_file_string = fullfile(path_string,file_string);
                app.ImageFileEditField.Value = app.myofibril_data.image_file_string;
                
                if contains(file_string,'.nd2')
                    app.myofibril_data.image_file = bfopen(app.myofibril_data.image_file_string);
                    im = app.myofibril_data.image_file;
                    app.myofibril_data.image = im{1,1}{1,1};
                    sz = size(im{1,1});
                    app.ChannelSpinner.Value = 1;
                    app.ChannelSpinner.Limits = [1 sz(1)];
                    app.RefreshDisplay
                else
                    im = imread(app.myofibril_data.image_file_string);
                    app.myofibril_data.image = im;  
                end
                
                center_image_with_preserved_aspect_ratio(app.myofibril_data.image,app.ImageAxes)

            end
        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            value = app.ChannelSpinner.Value;
            im = app.myofibril_data.image_file;
            center_image_with_preserved_aspect_ratio(im{1,1}{value,1},app.ImageAxes)
        end

        % Button pushed function: SelectPointsButton
        function SelectPointsButtonPushed(app, event)

            if  isfield(app.myofibril_data,'profile')
                app.myofibril_data.profile = [];
                cla(app.ImageAxes)
                center_image_with_preserved_aspect_ratio(app.myofibril_data.image_file{1,1}{app.ChannelSpinner.Value,1},app.ImageAxes)
                app.RefreshDisplay
            end

            app.myofibril_data.profile.roi = drawpolyline(app.ImageAxes,'LineWidth',1E-32,'MarkerSize',10);


            x = app.myofibril_data.profile.roi.Position(:,1);
            y = app.myofibril_data.profile.roi.Position(:,2);
            cs = spline(x, y);
            app.myofibril_data.profile.xs = linspace(x(1), x(end), 1000);
            app.myofibril_data.profile.ys = ppval(cs, app.myofibril_data.profile.xs);

            hold(app.ImageAxes,'on')
            app.spline_line = plot(app.ImageAxes,app.myofibril_data.profile.xs,app.myofibril_data.profile.ys,"Color",'m','LineWidth',2);

            addlistener(app.myofibril_data.profile.roi,"ROIMoved",@(src,evt) update_profile(evt));
            app.ExtractProfiles


            function update_profile(evt)
                x_upt = app.myofibril_data.profile.roi.Position(:,1);
                y_upt = app.myofibril_data.profile.roi.Position(:,2);
                cs_upt = spline(x_upt, y_upt);
                xs_upt = linspace(x_upt(1), x_upt(end), 1000);
                ys_upt = ppval(cs_upt, xs_upt);
                app.myofibril_data.profile.xs = linspace(x_upt(1), x_upt(end), 1000);
                app.myofibril_data.profile.ys = ppval(cs_upt, app.myofibril_data.profile.xs);
                app.spline_line.XData = app.myofibril_data.profile.xs;
                app.spline_line.YData = app.myofibril_data.profile.ys;
                app.RefreshDisplay;
                app.ExtractProfiles;
                app.ImageAxes
            end

        end

        % Value changed function: ProminenceEditField
        function ProminenceEditFieldValueChanged(app, event)
            app.RefreshDisplay;
            app.ExtractProfiles;
        end

        % Value changed function: CalibrationumpxEditField
        function CalibrationumpxEditFieldValueChanged(app, event)
            app.RefreshDisplay;
            app.ExtractProfiles;
        end

        % Menu selected function: ExportResultsMenu
        function ExportResultsMenuSelected(app, event)
            [file_string,path_string] = uiputfile2( ...
                {'*.xlsx','Excel file'},'Enter Excel File Name For Analysis Results');

            if (path_string~=0)
                output_file_string = fullfile(path_string,file_string);

                sum_out.image_file = app.myofibril_data.image_file_string;
                sum_out.analyzed_image_number = app.ChannelSpinner.Value;
                sum_out.calibration_px_to_um = app.myofibril_data.calibration;

                for i = 1 : numel(app.myofibril_data.profile.sarcomere_lengths)

                    var = sprintf('sarcomere_length_%i_um',i);
                    sum_out.(var) = app.myofibril_data.profile.sarcomere_lengths(i)

                end

                sum_out.mean_sl_um = mean(app.myofibril_data.profile.sarcomere_lengths);
                sum_out.std_sl_um = std(app.myofibril_data.profile.sarcomere_lengths);
                sum_out.sem_sl_um = std(app.myofibril_data.profile.sarcomere_lengths)/...
                    sqrt(numel(app.myofibril_data.profile.sarcomere_lengths));
                sum_out.fwhm_um = app.myofibril_data.profile.fwhm;

                sarcomere.location_um = app.myofibril_data.profile.sarcomere_location';

                for i = 1 : numel(app.myofibril_data.profile.sarcomere_lengths)
                    var = sprintf('sarcomere_%i_intensity',i);
                    sarcomere.(var) = app.myofibril_data.profile.sarcomere_intensities(i,:)';
                end

                sum_profiles.location_um = sarcomere.location_um;
                vars = {'mean_sarcomere_profile','std_sarcomere_profile','sem_sarcomere_profile'};
                for i = 1 : numel(vars)
                    sum_profiles.(vars{i}) = app.myofibril_data.profile.(vars{i})';
                end

                try
                    delete(output_file_string)
                end

                writetable(struct2table(sum_out),output_file_string,'Sheet','Summary')
                writetable(struct2table(sarcomere),output_file_string,'Sheet','Sarcomere Profiles')
                writetable(struct2table(sum_profiles),output_file_string,'Sheet','Summary Profiles')

                output_file_string = replace(output_file_string,'.xlsx','.myoprof');
                meta_out = app.myofibril_data;
                save(output_file_string,'meta_out')


            end
        end

        % Value changed function: ROIWidthpxEditField
        function ROIWidthpxEditFieldValueChanged(app, event)
            if  isfield(app.myofibril_data,'profile')
                app.RefreshDisplay;
                app.ExtractProfiles;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MyofibrilProfilerUIFigure = uifigure('Visible', 'off');
            app.MyofibrilProfilerUIFigure.Name = 'MyofibrilProfiler';
            app.Menu = uimenu(app.MyofibrilProfilerUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadImageMenu = uimenu(app.Menu);
            app.LoadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadImageMenuSelected, true);
            app.LoadImageMenu.Text = 'Load Image';
            app.ExportResultsMenu = uimenu(app.Menu);
            app.ExportResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResultsMenuSelected, true);
            app.ExportResultsMenu.Text = 'Export Results';
            app.LoadMeasurementMenu = uimenu(app.Menu);
            app.LoadMeasurementMenu.Visible = 'off';
            app.LoadMeasurementMenu.Text = 'Load Measurement';
            app.DataHandlerPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.DataHandlerPanel.Title = 'Data Handler';
            app.ImageFileEditFieldLabel = uilabel(app.DataHandlerPanel);
            app.ImageFileEditFieldLabel.Text = 'Image File';
            app.ImageFileEditField = uieditfield(app.DataHandlerPanel, 'text');
            app.ImageFileEditField.Editable = 'off';
            app.ImageDisplayPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ImageDisplayPanel.Title = 'Image Display';
            app.ImageAxes = uiaxes(app.ImageDisplayPanel);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ProfilerControlsPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ProfilerControlsPanel.Title = 'Profiler Controls';
            app.ChannelSpinnerLabel = uilabel(app.ProfilerControlsPanel);
            app.ChannelSpinnerLabel.Text = 'Channel';
            app.ChannelSpinner = uispinner(app.ProfilerControlsPanel);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.CalibrationumpxEditFieldLabel = uilabel(app.ProfilerControlsPanel);
            app.CalibrationumpxEditFieldLabel.Text = 'Calibration (um/px)';
            app.CalibrationumpxEditField = uieditfield(app.ProfilerControlsPanel, 'numeric');
            app.CalibrationumpxEditField.Limits = [0 Inf];
            app.CalibrationumpxEditField.ValueChangedFcn = createCallbackFcn(app, @CalibrationumpxEditFieldValueChanged, true);
            app.CalibrationumpxEditField.Value = 1;
            app.ProminenceEditFieldLabel = uilabel(app.ProfilerControlsPanel);
            app.ProminenceEditFieldLabel.Text = 'Prominence ';
            app.ProminenceEditField = uieditfield(app.ProfilerControlsPanel, 'numeric');
            app.ProminenceEditField.Limits = [0 1];
            app.ProminenceEditField.ValueChangedFcn = createCallbackFcn(app, @ProminenceEditFieldValueChanged, true);
            app.ProminenceEditField.Value = 0.5;
            app.ProfilerPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ProfilerPanel.Title = 'Profiler Panel';
            app.ProfileIntensity = uiaxes(app.ProfilerPanel);
            title(app.ProfileIntensity, 'Profile Intensity')
            xlabel(app.ProfileIntensity, 'Location (px)')
            ylabel(app.ProfileIntensity, 'Intensity (A.U.)')
            app.Sarcomeres = uiaxes(app.ProfilerPanel);
            title(app.Sarcomeres, 'Sarcomere Intensities')
            xlabel(app.Sarcomeres, 'Location (um)')
            ylabel(app.Sarcomeres, 'Normalized Intensity')
            app.SarcomereMean = uiaxes(app.ProfilerPanel);
            title(app.SarcomereMean, 'Average Sarcomere Intensity')
            xlabel(app.SarcomereMean, 'Location (um)')
            ylabel(app.SarcomereMean, 'Normalized Intensity')
            app.SummaryTable = uitable(app.ProfilerPanel);
            app.SummaryTable.ColumnName = {'Profile Number'; 'Mean SL (um)'; 'FWHM (um)'};
            app.SummaryTable.RowName = {};
            app.ROIControlsPanel = uipanel(app.MyofibrilProfilerUIFigure);
            app.ROIControlsPanel.Title = 'ROI Controls';
            app.SelectPointsButton = uibutton(app.ROIControlsPanel, 'push');
            app.SelectPointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsButtonPushed, true);
            app.SelectPointsButton.Text = 'Select Points';
            app.ROIWidthpxEditFieldLabel = uilabel(app.ROIControlsPanel);
            app.ROIWidthpxEditFieldLabel.Text = 'ROI Width (px)';
            app.ROIWidthpxEditField = uieditfield(app.ROIControlsPanel, 'numeric');
            app.ROIWidthpxEditField.ValueChangedFcn = createCallbackFcn(app, @ROIWidthpxEditFieldValueChanged, true);
            app.ROIWidthpxEditField.Value = 1;
            app.ZLineStainCheckBox = uicheckbox(app.ROIControlsPanel);
            app.ZLineStainCheckBox.Text = 'Z-Line Stain';
            % Show the figure after all components are created
            app.MyofibrilProfilerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myofibril_profiler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MyofibrilProfilerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MyofibrilProfilerUIFigure)
        end
    end
end